classdef PV_Parameter_Estimation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Graph                           matlab.ui.control.UIAxes
        InputPanel                      matlab.ui.container.Panel
        ApplyButton                     matlab.ui.control.Button
        ThermalVoltageVEditFieldLabel   matlab.ui.control.Label
        VthEdit                         matlab.ui.control.NumericEditField
        NumberofCellsEditFieldLabel     matlab.ui.control.Label
        CellEdit                        matlab.ui.control.NumericEditField
        OutputPanel                     matlab.ui.container.Panel
        DarkCurrentAEditFieldLabel      matlab.ui.control.Label
        IoEdit                          matlab.ui.control.NumericEditField
        LightCurrentAEditFieldLabel     matlab.ui.control.Label
        IphEdit                         matlab.ui.control.NumericEditField
        SeriesResistanceEditFieldLabel  matlab.ui.control.Label
        RsEdit                          matlab.ui.control.NumericEditField
        ShuntResistanceEditFieldLabel   matlab.ui.control.Label
        RshEdit                         matlab.ui.control.NumericEditField
        IdealityFactorLabel             matlab.ui.control.Label
        nEdit                           matlab.ui.control.NumericEditField
        FilePanel                       matlab.ui.container.Panel
        BrowseButton                    matlab.ui.control.Button
        FileEdit                        matlab.ui.control.EditField
        DescriptionLabel                matlab.ui.control.Label
    end


    methods (Access = private)
    
        function results = func(app)
            
        end
        
    end

    methods (Access = public)
    
        function results = func2(app)
            
        end
        
    end


    methods (Access = private)

        % Callback function
        function VthEditValueChanged(app, event)
            value = app.VthEdit.Value;
            
        end

        % Value changing function: FileEdit
        function FileEditValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Callback function: BrowseButton, FilePanel
        function BrowseButtonPushed(app, event)
             % get the Excel file to read in
             [filename, pathname] = uigetfile({'.xlsx'},'File Selector');
             excelFullfilename = fullfile(pathname, filename);
             set(event.FullPathName, 'String', excelFullfilename);
             % read the data from the file
             [num,txt,raw] = xlsread(excelFullfilename);
             % get the file name part
             [pathstr,name,ext] = fileparts(excelFullfilename);
             % create the mat filename equivalent
             matFullfilename = fullfile(pathstr, [name '.mat']);
             % save the data
             %?
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Graph = uiaxes(app.UIFigure);
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.ApplyButton = uibutton(app.InputPanel, 'push');
            app.ApplyButton.Text = 'Apply';
            app.ThermalVoltageVEditFieldLabel = uilabel(app.InputPanel);
            app.ThermalVoltageVEditFieldLabel.Text = 'Thermal Voltage (V):';
            app.VthEdit = uieditfield(app.InputPanel, 'numeric');
            app.NumberofCellsEditFieldLabel = uilabel(app.InputPanel);
            app.NumberofCellsEditFieldLabel.Text = 'Number of Cells:';
            app.CellEdit = uieditfield(app.InputPanel, 'numeric');
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.DarkCurrentAEditFieldLabel = uilabel(app.OutputPanel);
            app.DarkCurrentAEditFieldLabel.Text = 'Dark Current (A):';
            app.IoEdit = uieditfield(app.OutputPanel, 'numeric');
            app.IoEdit.Editable = 'off';
            app.LightCurrentAEditFieldLabel = uilabel(app.OutputPanel);
            app.LightCurrentAEditFieldLabel.Text = 'Light Current (A):';
            app.IphEdit = uieditfield(app.OutputPanel, 'numeric');
            app.IphEdit.Editable = 'off';
            app.SeriesResistanceEditFieldLabel = uilabel(app.OutputPanel);
            app.SeriesResistanceEditFieldLabel.Text = 'Series Resistance (Ω):';
            app.RsEdit = uieditfield(app.OutputPanel, 'numeric');
            app.RsEdit.Editable = 'off';
            app.ShuntResistanceEditFieldLabel = uilabel(app.OutputPanel);
            app.ShuntResistanceEditFieldLabel.Text = 'Shunt Resistance (Ω):';
            app.RshEdit = uieditfield(app.OutputPanel, 'numeric');
            app.RshEdit.Editable = 'off';
            app.IdealityFactorLabel = uilabel(app.OutputPanel);
            app.IdealityFactorLabel.Text = 'Ideality Factor:';
            app.nEdit = uieditfield(app.OutputPanel, 'numeric');
            app.nEdit.Editable = 'off';
            app.FilePanel = uipanel(app.UIFigure);
            app.FilePanel.Title = 'File Selection';
            app.FilePanel.SizeChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton = uibutton(app.FilePanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.FileEdit = uieditfield(app.FilePanel, 'text');
            app.FileEdit.ValueChangingFcn = createCallbackFcn(app, @FileEditValueChanging, true);
            app.DescriptionLabel = uilabel(app.FilePanel);
            app.DescriptionLabel.Text = 'Description';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PV_Parameter_Estimation

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end